classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Label         matlab.ui.control.Label
        Gauge         matlab.ui.control.NinetyDegreeGauge
        Label_2       matlab.ui.control.Label
        EditField     matlab.ui.control.NumericEditField
        aSliderLabel  matlab.ui.control.Label
        aSlider       matlab.ui.control.Slider
        Button        matlab.ui.control.Button
    end


    properties (Access = public)
        ifexist
    end

    properties (Access = private)
        zi % Description
    end

    methods (Access = public)
    
        function results = func(~,a,b,d)
            results = (a + b) * d;
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ifexist = 0;
        end

        % Value changing function: aSlider
        function aSliderValueChanging(app, event)
            a = event.Value;
            if app.ifexist == 0
                b = 0;
            else
                b = app.zi.bKnob.Value;
            end
            beishu = app.EditField.Value;
            result = func(app,a,b,beishu);
            app.Gauge.Value = result;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.zi = app2(app,5);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.zi)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '计算结果';
            app.Gauge = uigauge(app.UIFigure, 'ninetydegree');
            app.Gauge.Limits = [0 1000];
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '倍数';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.aSliderLabel = uilabel(app.UIFigure);
            app.aSliderLabel.Text = 'a';
            app.aSlider = uislider(app.UIFigure);
            app.aSlider.Limits = [0 10];
            app.aSlider.ValueChangingFcn = createCallbackFcn(app, @aSliderValueChanging, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '打开子窗口';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end