classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        bKnobLabel  matlab.ui.control.Label
        bKnob       matlab.ui.control.Knob
        Button      matlab.ui.control.Button
        Label       matlab.ui.control.Label
        Slider      matlab.ui.control.Slider
    end


    properties (Access = private)
        zhuapp % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, beishu)
            app.zhuapp = mainapp;
            app.Slider.Value = beishu;
            app.zhuapp.ifexist = 1;
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.zhuapp.EditField.Value = changingValue;
        end

        % Value changing function: bKnob
        function bKnobValueChanging(app, event)
            changingValue = event.Value;
            result = func(app.zhuapp,app.zhuapp.aSlider.Value,changingValue,app.zhuapp.EditField.Value);
            app.zhuapp.Gauge.Value = result;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.zhuapp.ifexist = 0;
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.bKnobLabel = uilabel(app.UIFigure);
            app.bKnobLabel.Text = 'b';
            app.bKnob = uiknob(app.UIFigure, 'continuous');
            app.bKnob.ValueChangingFcn = createCallbackFcn(app, @bKnobValueChanging, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '关闭';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '倍数';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end