classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Menu      matlab.ui.container.Menu
        Menu_2    matlab.ui.container.Menu
        Menu_3    matlab.ui.container.Menu
        Menu_5    matlab.ui.container.Menu
        Menu_4    matlab.ui.container.Menu
        Menu_6    matlab.ui.container.Menu
        ListBox   matlab.ui.control.ListBox
        TextArea  matlab.ui.control.TextArea
    end


    properties (Access = private)
        txt % Description
    end


    methods (Access = private)

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            [~,app.txt]=xlsread('李白.xlsx');
            title = app.txt(:,1);
            app.ListBox.Items = title;
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            [~,app.txt]=xlsread('王维.xlsx');
            title = app.txt(:,1);
            app.ListBox.Items = title;
        end

        % Menu selected function: Menu_4
        function Menu_4Selected(app, event)
            [~,app.txt]=xlsread('李清照.xlsx');
            title = app.txt(:,1);
            app.ListBox.Items = title;
        end

        % Menu selected function: Menu_6
        function Menu_6Selected(app, event)
            [~,app.txt]=xlsread('秦观.xlsx');
            title = app.txt(:,1);
            app.ListBox.Items = title;
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            title = app.txt(:,1);
            ind = find(strcmp(title,value));
            cont = app.txt(ind,2);
            
            %% 整理
            cont = char(cont);
            ind_1 = strfind(cont,'。');
            ind_2 = strfind(cont,'？');
            ind_3 = strfind(cont,'！');
            ind = sort([0 ind_1 ind_2 ind_3]);
            cont_show = {value};
            for i = 1:length(ind)-1
                part={cont(ind(i)+1:ind(i+1))};
                cont_show=[cont_show;part];
            end
            app.TextArea.Value = cont_show;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '唐诗';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '李白';
            app.Menu_3 = uimenu(app.Menu);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '王维';
            app.Menu_5 = uimenu(app.UIFigure);
            app.Menu_5.Text = '宋词';
            app.Menu_4 = uimenu(app.Menu_5);
            app.Menu_4.MenuSelectedFcn = createCallbackFcn(app, @Menu_4Selected, true);
            app.Menu_4.Text = '李清照';
            app.Menu_6 = uimenu(app.Menu_5);
            app.Menu_6.MenuSelectedFcn = createCallbackFcn(app, @Menu_6Selected, true);
            app.Menu_6.Text = '秦观';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end