classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        a1SliderLabel  matlab.ui.control.Label
        a1Slider       matlab.ui.control.Slider
        a2SliderLabel  matlab.ui.control.Label
        a2Slider       matlab.ui.control.Slider
        YSliderLabel   matlab.ui.control.Label
        YSlider        matlab.ui.control.Slider
        YKnobLabel     matlab.ui.control.Label
        YKnob          matlab.ui.control.Knob
        KnobLabel      matlab.ui.control.Label
        Knob           matlab.ui.control.DiscreteKnob
        Label_2        matlab.ui.control.Label
        Lamp           matlab.ui.control.Lamp
        Label          matlab.ui.control.Label
        Switch         matlab.ui.control.RockerSwitch
        UIAxes         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        pinlv,a1,a2,xiangwei,x,y,fs % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.y,app.fs]=audioread('10344.wav');
            app.a1=1;
            app.a2=1;
            app.pinlv=1;
            app.xiangwei=0;
            app.x=linspace(0,2*pi,500);
            y1=app.a1*sin(app.x);
            y2=app.a2*sin(app.pinlv*app.x+app.xiangwei);
            
            plot(app.UIAxes,y1,y2);
            
        end

        % Value changing function: a1Slider
        function a1SliderValueChanging(app, event)
            app.a1 = event.Value;
            y1=app.a1*sin(app.x);
            y2=app.a2*sin(app.pinlv*app.x+app.xiangwei);
            
            plot(app.UIAxes,y1,y2);
            
        end

        % Value changing function: a2Slider
        function a2SliderValueChanging(app, event)
            app.a2 = event.Value;
            y1=app.a1*sin(app.x);
            y2=app.a2*sin(app.pinlv*app.x+app.xiangwei);
            
            plot(app.UIAxes,y1,y2);
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            app.xiangwei = event.Value;
            y1=app.a1*sin(app.x);
            y2=app.a2*sin(app.pinlv*app.x+app.xiangwei);
            
            plot(app.UIAxes,y1,y2);
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            switch value
                case 'Off'
                    app.YKnob.Limits=[1,100];
                case 'Low'
                    app.YKnob.Limits=[1,10];
                case 'Medium'
                    app.YKnob.Limits=[10,50];
                case 'High'
                    app.YKnob.Limits=[50,100];
            end
        end

        % Value changing function: YKnob
        function YKnobValueChanging(app, event)
            app.pinlv = event.Value;
            y1=app.a1*sin(app.x);
            y2=app.a2*sin(app.pinlv*app.x+app.xiangwei);
            
            plot(app.UIAxes,y1,y2);
            
            if app.pinlv > 75 && strcmp(app.Switch.Value,'开')
                app.Lamp.Color='r';
                sound(app.y,app.fs);
            else
                app.Lamp.Color=[0 1 0];
            end
                
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.a1SliderLabel = uilabel(app.UIFigure);
            app.a1SliderLabel.Text = 'a1';
            app.a1Slider = uislider(app.UIFigure);
            app.a1Slider.Limits = [0 10];
            app.a1Slider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.a1Slider.ValueChangingFcn = createCallbackFcn(app, @a1SliderValueChanging, true);
            app.a1Slider.Value = 1;
            app.a2SliderLabel = uilabel(app.UIFigure);
            app.a2SliderLabel.Text = 'a2';
            app.a2Slider = uislider(app.UIFigure);
            app.a2Slider.Limits = [0 10];
            app.a2Slider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.a2Slider.ValueChangingFcn = createCallbackFcn(app, @a2SliderValueChanging, true);
            app.a2Slider.Value = 1;
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y相位';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [0 10];
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.YKnobLabel = uilabel(app.UIFigure);
            app.YKnobLabel.Text = 'Y频率';
            app.YKnob = uiknob(app.UIFigure, 'continuous');
            app.YKnob.ValueChangingFcn = createCallbackFcn(app, @YKnobValueChanging, true);
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = '频率换挡';
            app.Knob = uiknob(app.UIFigure, 'discrete');
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '警报';
            app.Lamp = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '警报开关';
            app.Switch = uiswitch(app.UIFigure, 'rocker');
            app.Switch.Items = {'关', '开'};
            app.Switch.Value = '关';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end