classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Label             matlab.ui.control.Label
        daikuane          matlab.ui.control.NumericEditField
        Label_2           matlab.ui.control.Label
        Label_3           matlab.ui.control.Label
        daikuanqixian     matlab.ui.control.DropDown
        Label_4           matlab.ui.control.Label
        lilvxuanze        matlab.ui.control.DropDown
        lilvxianshi       matlab.ui.control.NumericEditField
        Label_5           matlab.ui.control.Label
        ButtonGroup       matlab.ui.container.ButtonGroup
        dengebenxi        matlab.ui.control.RadioButton
        dengebenjin       matlab.ui.control.RadioButton
        Panel             matlab.ui.container.Panel
        Label_6           matlab.ui.control.Label
        zonge             matlab.ui.control.NumericEditField
        Label_7           matlab.ui.control.Label
        zonglixi_xianshi  matlab.ui.control.NumericEditField
        Label_8           matlab.ui.control.Label
        Label_9           matlab.ui.control.Label
        UITable           matlab.ui.control.Table
        jisuan            matlab.ui.control.Button
        qingchu           matlab.ui.control.Button
    end

    
    properties (Access = private)
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.lilvxianshi.Value=4.9*1.3;
        end

        % Value changed function: daikuane
        function daikuaneValueChanged(app, event)
            
        end

        % Button pushed function: jisuan
        function jisuanButtonPushed(app, event)
            %% 利率
            lilv=app.lilvxianshi.Value/1200;
            
            %% 本金
            benjin=app.daikuane.Value;
            %% 月数
            value = app.daikuanqixian.Value;
            switch value
                case '10年期(120月)'
                    yueshu=120;
                case '20年期(240月)'
                    yueshu=240;
                case '30年期(360月)'
                    yueshu=360;
            end
            
            if app.dengebenxi.Value == 1
                yuehuankuan=(benjin*10000*lilv*(1+lilv)^yueshu)/((1+lilv)^yueshu-1);
                zonglixi=yuehuankuan*yueshu-benjin*10000;
                app.zonglixi_xianshi.Value=zonglixi;
                app.zonge.Value=benjin;
                app.UITable.Data=yuehuankuan;
            end


            if app.dengebenjin.Value == 1
                yuebenjin=benjin*10000/yueshu;
                yihuan=0;
                yuelixi=zeros(yueshu,1);
                for i=1:yueshu
                    yuelixi(i)=(benjin*10000-yihuan)*lilv;
                    yihuan=yihuan+yuebenjin;
                end
                
                
                yuehuankuan=yuebenjin+yuelixi;
                zonglixi=sum(yuelixi);
                app.zonglixi_xianshi.Value=zonglixi;
                app.zonge.Value=benjin;
                
                app.UITable.Data=cellstr(num2str(yuehuankuan));
                
            end
        end

        % Value changed function: daikuanqixian
        function daikuanqixianValueChanged(app, event)
            
        end

        % Value changed function: lilvxuanze
        function lilvxuanzeValueChanged(app, event)
            %% 利率
            value = app.lilvxuanze.Value;
            switch value
                case '2018年6月1日利率上限(1.3倍)'
                    lilv=4.9*1.3;
                case '2018年6月1日利率上限(1.2倍)'
                    lilv=4.9*1.2;
                case '2018年6月1日利率上限(1.1倍)'
                    lilv=4.9*1.1;
                case '2018年6月1日基准利率'
                    lilv=4.9;
            end
            app.lilvxianshi.Value=lilv;
        end

        % Value changed function: lilvxianshi
        function lilvxianshiValueChanged(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '总贷款额';
            app.daikuane = uieditfield(app.UIFigure, 'numeric');
            app.daikuane.ValueChangedFcn = createCallbackFcn(app, @daikuaneValueChanged, true);
            app.daikuane.Limits = [0 Inf];
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '万元';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '贷款期限';
            app.daikuanqixian = uidropdown(app.UIFigure);
            app.daikuanqixian.Items = {'10年期(120月)', '20年期(240月)', '30年期(360月)'};
            app.daikuanqixian.ValueChangedFcn = createCallbackFcn(app, @daikuanqixianValueChanged, true);
            app.daikuanqixian.Value = '10年期(120月)';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '利率';
            app.lilvxuanze = uidropdown(app.UIFigure);
            app.lilvxuanze.Items = {'2018年6月1日利率上限(1.3倍)', '2018年6月1日利率上限(1.2倍)', '2018年6月1日利率上限(1.1倍)', '2018年6月1日基准利率'};
            app.lilvxuanze.ValueChangedFcn = createCallbackFcn(app, @lilvxuanzeValueChanged, true);
            app.lilvxuanze.Value = '2018年6月1日利率上限(1.3倍)';
            app.lilvxianshi = uieditfield(app.UIFigure, 'numeric');
            app.lilvxianshi.ValueChangedFcn = createCallbackFcn(app, @lilvxianshiValueChanged, true);
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '%';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = '贷款方式';
            app.dengebenxi = uiradiobutton(app.ButtonGroup);
            app.dengebenxi.Text = '等额本息';
            app.dengebenxi.Value = true;
            app.dengebenjin = uiradiobutton(app.ButtonGroup);
            app.dengebenjin.Text = '等额本金';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '计算数据';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '贷款金额';
            app.zonge = uieditfield(app.Panel, 'numeric');
            app.zonge.ValueDisplayFormat = '%.2f';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '总利息';
            app.zonglixi_xianshi = uieditfield(app.Panel, 'numeric');
            app.zonglixi_xianshi.ValueDisplayFormat = '%.2f';
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Text = '万元';
            app.Label_9 = uilabel(app.Panel);
            app.Label_9.Text = '元';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'月还款'};
            app.UITable.RowName = {};
            app.jisuan = uibutton(app.UIFigure, 'push');
            app.jisuan.ButtonPushedFcn = createCallbackFcn(app, @jisuanButtonPushed, true);
            app.jisuan.Text = '计算';
            app.qingchu = uibutton(app.UIFigure, 'push');
            app.qingchu.Text = '清除';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end