classdef daikuan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Label            matlab.ui.control.Label
        zongdaikuane     matlab.ui.control.NumericEditField
        Label_2          matlab.ui.control.Label
        Label_3          matlab.ui.control.Label
        daikuanqixian    matlab.ui.control.DropDown
        Label_4          matlab.ui.control.Label
        lilvxuanze       matlab.ui.control.DropDown
        lilvxianshi      matlab.ui.control.NumericEditField
        Label_5          matlab.ui.control.Label
        ButtonGroup      matlab.ui.container.ButtonGroup
        dengebenxi       matlab.ui.control.RadioButton
        dengebenjin      matlab.ui.control.RadioButton
        jisuan           matlab.ui.control.Button
        qingchu          matlab.ui.control.Button
        Panel            matlab.ui.container.Panel
        Label_6          matlab.ui.control.Label
        zonge            matlab.ui.control.NumericEditField
        Label_7          matlab.ui.control.Label
        zonglixixianshi  matlab.ui.control.NumericEditField
        UITable          matlab.ui.control.Table
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.lilvxianshi.Value=1.3*4.9;
        end

        % Button pushed function: jisuan
        function jisuanButtonPushed(app, event)
            lilv=app.lilvxianshi.Value/1200;
            benjin=app.zongdaikuane.Value;
            value=app.daikuanqixian.Value;
            switch value
                case '10年期(120月)'
                    yueshu=120;
                case '10年期(240月)'
                    yueshu=240;
                case '10年期(360月)'
                    yueshu=360;
            end
            if app.dengebenxi.Value == 1
                %等额本息
                yuehuankuan=(benjin*10000*lilv*(1+lilv)^yueshu)/((1+lilv)^yueshu-1);
                zonglixi=yuehuankuan*yueshu-benjin*10000;
                app.zonge.Value=benjin;
                app.zonglixixianshi.Value=zonglixi;
                app.UITable.Data={num2str(yuehuankuan)};
                
            end
            if app.dengebenjin.Value == 1
                %等额本金
                yuebenjin=benjin*10000/yueshu;
                
                yihuan=0;
                yuelixi=zeros(yueshu,1);
                for i=1:yueshu
                    yuelixi(i)=(benjin*10000-yihuan)*lilv;
                    yihuan=yihuan+yuebenjin;
                end
                zonglixi=sum(yuelixi);
                yuehuankuan=yuebenjin+yuelixi;
                app.zonge.Value=benjin;
                app.zonglixixianshi.Value=zonglixi;
                app.UITable.Data=cellstr(num2str(yuehuankuan));
                
            end
            
        end

        % Value changed function: lilvxuanze
        function lilvxuanzeValueChanged(app, event)
            value = app.lilvxuanze.Value;
            switch value
                case '2018年6月1日利率上限(1.3倍)'
                    lilv=1.3*4.9;
                case '2018年6月1日利率上限(1.2倍)'
                    lilv=1.2*4.9;
                case '2018年6月1日利率上限(1.1倍)'
                    lilv=1.1*4.9;
                case '2018年6月1日基准利率'
                    lilv=4.9;
            end
            app.lilvxianshi.Value=lilv;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '总贷款额';
            app.zongdaikuane = uieditfield(app.UIFigure, 'numeric');
            app.zongdaikuane.Limits = [0 Inf];
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '万元';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '贷款期限';
            app.daikuanqixian = uidropdown(app.UIFigure);
            app.daikuanqixian.Items = {'10年期(120月)', '10年期(240月)', '10年期(360月)'};
            app.daikuanqixian.Value = '10年期(120月)';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '利率';
            app.lilvxuanze = uidropdown(app.UIFigure);
            app.lilvxuanze.Items = {'2018年6月1日利率上限(1.3倍)', '2018年6月1日利率上限(1.2倍)', '2018年6月1日利率上限(1.1倍)', '2018年6月1日基准利率'};
            app.lilvxuanze.ValueChangedFcn = createCallbackFcn(app, @lilvxuanzeValueChanged, true);
            app.lilvxuanze.Value = '2018年6月1日利率上限(1.3倍)';
            app.lilvxianshi = uieditfield(app.UIFigure, 'numeric');
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '%';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = '还款方式';
            app.dengebenxi = uiradiobutton(app.ButtonGroup);
            app.dengebenxi.Text = '等额本息';
            app.dengebenxi.Value = true;
            app.dengebenjin = uiradiobutton(app.ButtonGroup);
            app.dengebenjin.Text = '等额本金';
            app.jisuan = uibutton(app.UIFigure, 'push');
            app.jisuan.ButtonPushedFcn = createCallbackFcn(app, @jisuanButtonPushed, true);
            app.jisuan.Icon = 'open_16px_1142209_easyicon.net.png';
            app.jisuan.Text = '计算';
            app.qingchu = uibutton(app.UIFigure, 'push');
            app.qingchu.Text = '清除';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '计算数据';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '总贷款额';
            app.zonge = uieditfield(app.Panel, 'numeric');
            app.zonge.ValueDisplayFormat = '%.2f';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '总利息';
            app.zonglixixianshi = uieditfield(app.Panel, 'numeric');
            app.zonglixixianshi.ValueDisplayFormat = '%.2f';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'月还款'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = daikuan

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end