classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
        Button    matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Button_3  matlab.ui.control.Button
    end


    properties (Access = private)
        h % Description
        x
        y
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.x = linspace(0,2*pi,200);
            app.y = sin(app.x) +cos(3*app.x);
            app.h = plot(app.UIAxes,app.x,app.y);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            c = uisetcolor([1 1 0],'曲线颜色');
            app.h.Color = c;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
%             uisave;%保存所有变量
              x1 = app.x;
              y1 = app.y;
%               uisave({'x1','y1'});
              uisave({'x1','y1'},'x_y')
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
%             selection = uiconfirm(app.UIFigure,'要关了程序吗？','关闭','Icon','warning');% question info success warning error none
            f =  app.UIFigure;
            mesg = '要关了程序吗？';
            title = '关程序';
            option = {'关了','不关','关这个窗口'};
            selection = uiconfirm(f,mesg,title,'Icon','question','options',option,'DefaultOption',2,'CancelOption',3);
            switch selection
                case '关了'
                    delete(app);
                case '不关'
                    msgbox({'提醒','不关了'});
                case '关这个窗口'
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '颜色选择';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '变量保存';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '程序关闭';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end