classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Label        matlab.ui.control.Label
        SliderLabel  matlab.ui.control.Label
        Slider       matlab.ui.control.Slider
        Button       matlab.ui.control.Button
    end


    properties (Access = private)
        zhu
        x% Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, x1)
            app.zhu = mainapp;
            app.x = x1;
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Label.Text = num2str(changingValue);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            y = str2double(app.Label.Text)*(sin(app.x)+cos(app.x));
            plot(app.zhu.UIAxes,app.x,y);
            
%             app.zhu.UIFigure.Visible = 'on';
            app.zhu.Button.Enable = 'on';
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '返回';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end